classdef climatewedges < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        dataInputTab                    matlab.ui.container.TabGroup
        Files                           matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        WedgesTextFileEditFieldLabel    matlab.ui.control.Label
        ConstraintsTextFile             matlab.ui.control.EditField
        StrategyTextFile                matlab.ui.control.EditField
        ConstraintsTextFileLabel        matlab.ui.control.Label
        StrategyTextFileEditFieldLabel  matlab.ui.control.Label
        WedgesTextFile                  matlab.ui.control.EditField
        ErrorText                       matlab.ui.control.TextArea
        WedgesTextLamp                  matlab.ui.control.Lamp
        ConstraintsTextLamp             matlab.ui.control.Lamp
        StrategyTextLamp                matlab.ui.control.Lamp
        wedgesBrowse                    matlab.ui.control.Button
        constraintsBrowse               matlab.ui.control.Button
        strategyBrowse                  matlab.ui.control.Button
        GraphsTab                       matlab.ui.container.Tab
        WedgesByTable                   matlab.ui.container.Tab
        WedgeTable                      matlab.ui.control.Table
        AddRowButton                    matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        DeleteRowButton                 matlab.ui.control.Button
        ConstraintsbyTable              matlab.ui.container.Tab
        ConstraintsTable                matlab.ui.control.Table
        StrategybyTable                 matlab.ui.container.Tab
        StratTable                      matlab.ui.control.Table
    end


    properties (Access = private)
        wedgeHeader %cell of each name of wedge struct field
        fileError = 'Error in %s Text File, "%s".  Please check that %s correct.' %file type, filename, specific error
        fileCancel = 'File selection cancelled.'
%         allFilled = [0 0 0]; % are elements full?
    end

    properties (Access = public)
        wedges % struct full of wedges
        constraints % struct with portfolio constraints
        strat %struct with all strategy info
        files = {'','',''}; %file locations
    end

    methods (Access = public)
    
        function [] = readWedges(app, file_name)
            %readWedges  Checks if it is appropriate file
            %get wedges info into struct
            %   Each wedge has it's ID as it's position in struct, as well as ID
            %   each element of header is in field
            fid = fopen(file_name); 
            app.wedges = struct; %re - create
            app.files{1} = justFileName(app,file_name);
            if(fid == -1)
                app.ErrorText.Value = sprintf(app.fileError, 'Wedges', app.files{1}, 'file and and path are');
                app.WedgesTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            line = fgetl(fid);
            if strcmp(line,'ID;Category;StrategyName;Sector;Description;Needs;Challenges;Cost') == 0  %Checks that it is correct wedges file
                app.ErrorText.Value = sprintf(app.fileError, 'Wedges', app.files{1}, 'file formatting is');
                app.WedgesTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            header = strsplit(line, ';'); %split at semicolons
            app.wedges = struct(); %make struct
            for indField = 1:numel(header)
                app.wedges.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line, ';');
                if(numel(parts) ~= numel(header)) %wrong number of items
                    app.ErrorText.Value = sprintf(app.fileError, 'Wedges', app.files{1}, 'file formatting is');
                    app.WedgesTextFile.FontColor = 'red';
                    app.ErrorText.FontColor = 'red';
                    app.wedges = struct; %recreate so empty
                    return
                end
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    end %if
                    app.wedges(parts{1}).(header{indF})= parts{indF};
                        %add each part into the struct, ID is also it's position
        
                end %for loop
                line = fgetl(fid);
            end% while loop
            app.wedgeHeader = header; %assign to global
            app.ErrorText.Value = ''; %reset to blank
        end
        
        function [] = readConstraints(app, file_name)
            %readConstraints Checks if it is appropriate file
            %Put 'em in a struct
            %   each sector is a field, with limits as double value
            fid = fopen(file_name);%open file
            app.constraints = struct; %re - create
            app.files{2} = justFileName(app,file_name);
            if(fid == -1)
                app.ErrorText.Value = sprintf(app.fileError, 'Constraints', app.files{2}, 'file and and path are');
                app.ConstraintsTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            line = fgetl(fid);
            if strcmp(line,'Sector Constraints') == 0  %Checks that it is correct wedges file
                app.ErrorText.Value = sprintf(app.fileError, 'Constraints', app.files{2}, 'file formatting is');
                app.ConstraintsTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            header = strsplit(line); %split at semicolons
            app.constraints = struct(); %make struct
            line = fgetl(fid);
            while(~isnumeric(line))
                parts = strsplit(line);
                if(numel(parts) ~= numel(header)) %wrong number of items
                    app.ErrorText.Value = sprintf(app.fileError, 'Constraints', app.files{2}, 'file formatting is');
                    app.ConstraintsTextFile.FontColor = 'red';
                    app.ErrorText.FontColor = 'red';
                    app.constraints = struct; %recreate so it's empty
                    return
                end
                parts{2} = str2double(parts{2}); %conver to doubles
                app.constraints.(parts{1})= parts{2};
                    %add each part into the struct, ID is also it's position
                line = fgetl(fid);
            end% while loop
            app.ErrorText.Value = ''; %reset to blank
        end
        
        function [] = readStrat(app, file_name)
            % Checks if it is appropriate file
            % Put 'em in a struct
            fid = fopen(file_name);%open file
            app.strat = struct; %re - create
            app.files{3} = justFileName(app,file_name);
            if(fid == -1)
                app.ErrorText.Value = sprintf(app.fileError, 'Strategy', app.files{3}, 'file and and path are');
                app.StrategyTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            line = fgetl(fid);
            if strcmp(line,'ID YearStart') == 0  %Checks that it is correct wedges file
                app.ErrorText.Value = sprintf(app.fileError, 'Strategy', app.files{3}, 'file formatting is');
                app.StrategyTextFile.FontColor = 'red';
                app.ErrorText.FontColor = 'red';
                return
            end
            header = strsplit(line); %split at semicolons
            app.strat = struct(); %make struct
            for indField = 1:numel(header)
                app.strat.(header{indField}) = NaN; %make all the fields w/ dummy val
            end
            line = fgetl(fid);
            indSpot = 1; %increment through each spot
            while(~isnumeric(line))
                parts = strsplit(line);
                if(numel(parts) ~= numel(header)) %wrong number of items
                    app.ErrorText.Value = sprintf(app.fileError, 'Strategy', app.files{3}, 'file formatting is');
                    app.StrategyTextFile.FontColor = 'red';
                    app.ErrorText.FontColor = 'red';
                    app.strat = struct; %recreate so it reads empty
                    return
                end
                for indF = 1:numel(header)
                    if(~isnan(str2double(parts{indF})))
                        parts{indF} = str2double(parts{indF}); %conver to doubles
                    else %there are text things, and there shouldn't be
                        app.ErrorText.Value = sprintf(app.fileError, 'Strategy', app.files{3}, 'file formatting is');
                        app.StrategyTextFile.FontColor = 'red';
                        app.ErrorText.FontColor = 'red';
                        app.strat = struct; %recreate so it reads empty
                        return
                    end %if
                    app.strat(indSpot).(header{indF})= parts{indF};
                    %add each part into the struct, ID is also it's position
                    
                end %for loop
                line = fgetl(fid);
                indSpot = indSpot + 1; %next spot
            end% while loop
            app.ErrorText.Value = ''; %reset to blank
        end
    end

    methods (Access = private)
    
        function [] = toggleInputFields(app)
            %toggleInputFields Startup cleanup
            %   See if structs are filled, and based on that
            %   disable input fields to keep it clean on start screen
            allFilled = [0 0 0]; %keep track of what is already stored
            if(isfield(app.wedges, 'ID')) %wedges is filled
                app.WedgesTextFile.Enable = 'off';
                app.WedgesTextFile.Value = app.files{1};
                app.WedgesTextLamp.Color = 'green';
                app.WedgesTextFile.FontColor = 'black';
                allFilled(1) = 1;
                unFormData = struct2cell(app.wedges); %unformatted data
                flipData(:,:) = unFormData(:,1,:);
                app.WedgeTable.Data = flipData';
            else
                app.WedgesTextFile.Enable = 'on';
                app.WedgesTextFile.Value = app.files{1};
                app.WedgesTextLamp.Color = 'red';
                app.WedgesTextFile.FontColor = 'red';
                allFilled(1) = 0;
            end
            if(isfield(app.constraints, 'Electricity') || isfield(app.constraints, ...
                    'Transportation') || isfield(app.constraints, 'Fuel'))
                %one of the fields exist, so we can move from here
                app.ConstraintsTextFile.Enable = 'off';
                app.ConstraintsTextFile.Value = app.files{2};
                app.ConstraintsTextLamp.Color = 'green';
                app.ConstraintsTextFile.FontColor = 'black';
                allFilled(2) = 1;
                unFormData = horzcat(fieldnames(app.constraints), struct2cell(app.constraints));%Next two lines puts data from app.constraints into table
                app.ConstraintsTable.Data = unFormData;
            else
                app.ConstraintsTextFile.Enable = 'on';
                app.ConstraintsTextFile.Value = app.files{2};
                app.ConstraintsTextLamp.Color = 'red';
                app.ConstraintsTextFile.FontColor = 'red';
                allFilled(2) = 0;
            end
            if(isfield(app.strat, 'ID')) %strat is filled
                app.StrategyTextFile.Enable = 'off';
                app.StrategyTextFile.Value = app.files{3};
                app.StrategyTextLamp.Color = 'green';
                app.StrategyTextFile.FontColor = 'black';
                allFilled(3) = 1;
                unFormData = struct2cell(app.strat); %Next three lines puts data from app.strat into table
                flipStratData(:,:) = unFormData(:,1,:);
                app.StratTable.Data = flipStratData';
            else
                app.StrategyTextFile.Enable = 'on';
                app.StrategyTextFile.Value = app.files{3};
                app.StrategyTextLamp.Color = 'red';
                app.StrategyTextFile.FontColor = 'red';
                allFilled(3) = 0;
            end
            
            %%%%% Let's not have this little thing.
%             if(all(allFilled == 1)) % we have all text files
%                 app.ErrorText.Value = 'All files entered.  Review the data on the table tab for each file, and when ready, press OK to continue.';
%                 app.ErrorText.BackgroundColor = 'green';
%                 app.ErrorText.FontColor = 'black';
%             elseif(all(allFilled(1:2) == 1)) %we have wedges and constraint
%                 app.ErrorText.Value = 'Wedges and Constraint files entered.  Review the data on the table tab for each file, and when ready, press OK to continue.  Inside, you can choose your strategy with live feedback.';
%                 app.ErrorText.BackgroundColor = 'green';
%                 app.ErrorText.FontColor = 'black';
%             end %if
        end
        
        function file = justFileName(app, filepath)
        %justFileName Split to get just name of file
            parts = strsplit(filepath,'/');
            file = parts{end}; %grab last element, the filename
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            numFiles = size(varargin); %how many files? should be 1, 2, or 3
            if(numFiles > 0)%we have at least 1?
                readWedges(app,varargin{1}); %assign variables, assigns to prop inside
            end
            if(numFiles > 1) %we have at least 2
                readConstraints(app,varargin{2});
            end
            if(numFiles == 3) %we have three files
                app.strategy = readStrategy(app,varargin{3});
            end
            toggleInputFields(app);
            
            
            %Code to test if structs fillign right
%             tempCell = struct2cell(app.wedges);
%             cell(:,:) = tempCell(:,1,:);
%             app.WedgeTable.Data = cell;
        end

        % Value changed function: ConstraintsTextFile
        function ConstraintsTextFileValueChanged(app, event)
            value = app.ConstraintsTextFile.Value;
            readConstraints(app,value);
            toggleInputFields(app);
                
        end

        % Value changed function: StrategyTextFile
        function StrategyTextFileValueChanged(app, event)
            value = app.StrategyTextFile.Value;
            readStrat(app,value);
            toggleInputFields(app);
            
        end

        % Value changed function: WedgesTextFile
        function WedgesTextFileValueChanged(app, event)
            value = app.WedgesTextFile.Value;
            readWedges(app,value);
            toggleInputFields(app);
        end

        % Button pushed function: wedgesBrowse
        function wedgesBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readWedges(app,filepath);
                app.files{1} = file; %overwrite so it has the right filename
            end
            toggleInputFields(app);
        end

        % Button pushed function: constraintsBrowse
        function constraintsBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readConstraints(app,filepath);
                app.files{2} = file;
            end
            toggleInputFields(app);
        end

        % Button pushed function: strategyBrowse
        function strategyBrowseButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            filepath = [path file]; %total file
            if(file == 0) %exited out
                app.ErrorText.Value = app.fileCancel;
                app.ErrorText.FontColor = 'black';
            else
                readStrat(app,filepath);
                app.files{3} = file;
            end
            toggleInputFields(app);
        end

        % Value changing function: ConstraintsTextFile
        function ConstraintsTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.ConstraintsTextFile.FontColor = 'black';
        end

        % Value changing function: StrategyTextFile
        function StrategyTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.ConstraintsTextFile.FontColor = 'black';
        end

        % Value changing function: WedgesTextFile
        function WedgesTextFileValueChanging(app, event)
            changingValue = event.Value;
            app.WedgesTextFile.FontColor = 'black';
        end

        % Callback function
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            ;lkjasd;lkjasdf;ljfds
        end

        % Callback function
        function OkButtonPushed(app, event)
            app.dataInputTab.Visible = 'off';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.dataInputTab = uitabgroup(app.UIFigure);
            app.Files = uitab(app.dataInputTab);
            app.Files.Title = 'Files';
            app.TextArea = uitextarea(app.Files);
            app.TextArea.Value = {'Please input the wedges, constraint, and strategy info.'};
            app.TextArea_2 = uitextarea(app.Files);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'You can enter the file names/filepaths here, or enter them manually into the tables on the other tabs.  If you have already entered data (through input argument or another method), it''s entry position will be greyed out.'; ''; 'Press Ok to continue to the app.'};
            app.WedgesTextFileEditFieldLabel = uilabel(app.Files);
            app.WedgesTextFileEditFieldLabel.Text = 'Wedges Text File';
            app.ConstraintsTextFile = uieditfield(app.Files, 'text');
            app.ConstraintsTextFile.ValueChangedFcn = createCallbackFcn(app, @ConstraintsTextFileValueChanged, true);
            app.ConstraintsTextFile.ValueChangingFcn = createCallbackFcn(app, @ConstraintsTextFileValueChanging, true);
            app.StrategyTextFile = uieditfield(app.Files, 'text');
            app.StrategyTextFile.ValueChangedFcn = createCallbackFcn(app, @StrategyTextFileValueChanged, true);
            app.StrategyTextFile.ValueChangingFcn = createCallbackFcn(app, @StrategyTextFileValueChanging, true);
            app.ConstraintsTextFileLabel = uilabel(app.Files);
            app.ConstraintsTextFileLabel.Text = 'Constraints Text File';
            app.StrategyTextFileEditFieldLabel = uilabel(app.Files);
            app.StrategyTextFileEditFieldLabel.Text = 'Strategy Text File';
            app.WedgesTextFile = uieditfield(app.Files, 'text');
            app.WedgesTextFile.ValueChangedFcn = createCallbackFcn(app, @WedgesTextFileValueChanged, true);
            app.WedgesTextFile.ValueChangingFcn = createCallbackFcn(app, @WedgesTextFileValueChanging, true);
            app.ErrorText = uitextarea(app.Files);
            app.ErrorText.Editable = 'off';
            app.WedgesTextLamp = uilamp(app.Files);
            app.ConstraintsTextLamp = uilamp(app.Files);
            app.StrategyTextLamp = uilamp(app.Files);
            app.wedgesBrowse = uibutton(app.Files, 'push');
            app.wedgesBrowse.ButtonPushedFcn = createCallbackFcn(app, @wedgesBrowseButtonPushed, true);
            app.wedgesBrowse.Text = 'Browse...';
            app.constraintsBrowse = uibutton(app.Files, 'push');
            app.constraintsBrowse.ButtonPushedFcn = createCallbackFcn(app, @constraintsBrowseButtonPushed, true);
            app.constraintsBrowse.Text = 'Browse...';
            app.strategyBrowse = uibutton(app.Files, 'push');
            app.strategyBrowse.ButtonPushedFcn = createCallbackFcn(app, @strategyBrowseButtonPushed, true);
            app.strategyBrowse.Text = 'Browse...';
            app.GraphsTab = uitab(app.dataInputTab);
            app.GraphsTab.Title = 'Graphs';
            app.WedgesByTable = uitab(app.dataInputTab);
            app.WedgesByTable.Title = 'Wedges by Table';
            app.WedgeTable = uitable(app.WedgesByTable);
            app.WedgeTable.ColumnName = {'ID'; 'Category'; 'SectorName'; 'Sector'; 'Description'; 'Needs'; 'Challenges'; 'Cost'};
            app.WedgeTable.RowName = {};
            app.WedgeTable.ColumnEditable = [true false false false false false false false];
            app.AddRowButton = uibutton(app.WedgesByTable, 'push');
            app.AddRowButton.Text = 'Add Row';
            app.Button2 = uibutton(app.WedgesByTable, 'push');
            app.Button2.Text = 'Button2';
            app.DeleteRowButton = uibutton(app.WedgesByTable, 'push');
            app.DeleteRowButton.Text = 'Delete Row';
            app.ConstraintsbyTable = uitab(app.dataInputTab);
            app.ConstraintsbyTable.Title = 'Constraints by Table';
            app.ConstraintsTable = uitable(app.ConstraintsbyTable);
            app.ConstraintsTable.ColumnName = {'Sector'; 'Constraints'};
            app.ConstraintsTable.RowName = {};
            app.StrategybyTable = uitab(app.dataInputTab);
            app.StrategybyTable.Title = 'Strategy by Table';
            app.StratTable = uitable(app.StrategybyTable);
            app.StratTable.ColumnName = {'ID'; 'YearStart'};
            app.StratTable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = climatewedges(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end